classdef mth5_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        MTH5fileh5Menu            matlab.ui.container.Menu
        ExportMenu                matlab.ui.container.Menu
        toWorkspaceMenu           matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        devlabel                  matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        BrowserLabel              matlab.ui.control.Label
        GridPlot                  matlab.ui.container.GridLayout
        TextArea                  matlab.ui.control.TextArea
        DescriptionTextAreaLabel  matlab.ui.control.Label
        Tree                      matlab.ui.container.Tree
        GridLayout2               matlab.ui.container.GridLayout
        FileLabel                 matlab.ui.control.Label
        filename                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        UIAxes % Axes for quick-look dataset plotting
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create plotting axes (hidden until a dataset is selected)
            app.UIAxes = uiaxes( ...
                'Parent',   app.GridPlot, ...
                'FontName', 'Verdana', ...
                'FontSize', 11, ...
                'Visible',  'off' ...
            );
        end

        % Menu selected function: MTH5fileh5Menu
        function LoadfileButtonPushed(app, event)
            % Pick an MTH5/HDF5 file
            [file, path] = uigetfile({'*.h5;*.hdf5;*.mth5','MTH5/HDF5 files'}, ...
                                     'Select MTH5 (HDF5) file');
        
            % User cancelled
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
        
            % Store selected file path in the UI
            app.filename.Text = fullfile(path, file);
        
            % Digest file structure (lightweight)
            tree = mth5_load(app.filename.Text);
        
            % Populate UI tree
            mth5_populate_uitree(tree, app.Tree);
        
            % Bind selection callback (dataset preview + info panel)
            app.Tree.SelectionChangedFcn = @(src, evt) mth5_on_select(src, evt, app.TextArea, app.UIAxes);
        end

        % Menu selected function: toWorkspaceMenu
        function toWorkspaceMenuSelected(app, event)
            % Validate that a file is loaded
            if isempty(app.filename.Text)
                uialert(app.UIFigure, 'No file loaded. Please load an MTH5 file first.', 'Export');
                return;
            end
        
            % Full export
            out = mth5_export(app.filename.Text);
        
            % Export to base workspace
            assignin('base', 'out', out);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MTH5 Viewer';
            app.UIFigure.Icon = 'mth5_logo2.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.MTH5fileh5Menu = uimenu(app.LoadMenu);
            app.MTH5fileh5Menu.MenuSelectedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.MTH5fileh5Menu.Text = 'MTH5 file (*.h5)';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.toWorkspaceMenu = uimenu(app.ExportMenu);
            app.toWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @toWorkspaceMenuSelected, true);
            app.toWorkspaceMenu.Text = 'to Workspace';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.filename = uilabel(app.GridLayout2);
            app.filename.Text = '';
            app.FileLabel = uilabel(app.GridLayout2);
            app.FileLabel.Text = 'File:';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Tree = uitree(app.GridLayout3);
            app.DescriptionTextAreaLabel = uilabel(app.GridLayout3);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.TextArea = uitextarea(app.GridLayout3);
            app.GridPlot = uigridlayout(app.GridLayout3);
            app.BrowserLabel = uilabel(app.GridLayout3);
            app.BrowserLabel.Text = 'Browser';
            app.devlabel = uilabel(app.GridLayout);
            app.devlabel.Text = 'Castro (2026)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mth5_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end